classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ForwardandInverseKinamaticsSolverPanel  matlab.ui.container.Panel
        a1                          matlab.ui.control.NumericEditField
        NumberofLinksLabel_15       matlab.ui.control.Label
        a2                          matlab.ui.control.NumericEditField
        NumberofLinksLabel_14       matlab.ui.control.Label
        NumberofLinksLabel_13       matlab.ui.control.Label
        a3                          matlab.ui.control.NumericEditField
        d1                          matlab.ui.control.NumericEditField
        d2                          matlab.ui.control.NumericEditField
        d3                          matlab.ui.control.NumericEditField
        NumberofLinksLabel_12       matlab.ui.control.Label
        NumberofLinksLabel_11       matlab.ui.control.Label
        NumberofLinksLabel_10       matlab.ui.control.Label
        alpha1                      matlab.ui.control.NumericEditField
        alpha2                      matlab.ui.control.NumericEditField
        alpha3                      matlab.ui.control.NumericEditField
        NumberofLinksLabel_9        matlab.ui.control.Label
        NumberofLinksLabel_8        matlab.ui.control.Label
        NumberofLinksLabel_7        matlab.ui.control.Label
        theta1                      matlab.ui.control.NumericEditField
        theta2                      matlab.ui.control.NumericEditField
        theta3                      matlab.ui.control.NumericEditField
        GetForwardKinamaticsButton  matlab.ui.control.Button
        z                           matlab.ui.control.NumericEditField
        NumberofLinksLabel_6        matlab.ui.control.Label
        y                           matlab.ui.control.NumericEditField
        NumberofLinksLabel_5        matlab.ui.control.Label
        x                           matlab.ui.control.NumericEditField
        NumberofLinksLabel_4        matlab.ui.control.Label
        GetInverseKinamaticsButton  matlab.ui.control.Button
        NumberofLinksLabel_3        matlab.ui.control.Label
        NumberofLinksLabel_2        matlab.ui.control.Label
        NumberofLinksLabel          matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        ThetaEditField% Array of edit fields for theta values
        thetaValues     % Array to store theta values
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetForwardKinamaticsButton
        function GetForwardKinamaticsButtonPushed(app, event)

            Theta1 = app.theta1.Value;
            Theta2 = app.theta2.Value;
            Theta3 = app.theta3.Value;
            function T = dh_transform(theta, d, a, alpha)
                % Conv degree to radian
                theta = deg2rad(theta);
                alpha = deg2rad(alpha);
            
                % Calc transformation matrix
                T = [
                    cos(theta), -sin(theta) * cos(alpha), sin(theta) * sin(alpha), a * cos(theta);
                    sin(theta), cos(theta) * cos(alpha), -cos(theta) * sin(alpha), a * sin(theta);
                    0, sin(alpha), cos(alpha), d;
                    0, 0, 0, 1
                ];
            end


            if (app.a1.Value == 0) && (app.a2.Value == 0) && (app.a3.Value == 0)
                app.a1.Value = 10; 
                app.a2.Value = 10; 
                app.a3.Value = 10;
            else

            end

            % [θ, d, a, α]
            dh_params = [
                Theta1, app.d1.Value, app.a1.Value, app.alpha1.Value;  %  D-H joint 1
                Theta2, app.d2.Value, app.a2.Value, app.alpha2.Value;  %  D - H joint 2
                Theta3, app.d3.Value, app.a3.Value, app.alpha3.Value;  % D-H joint 3
            ];
        
            T = eye(4); % Identity matrix
        
            % Calculate transformation matrices for each joint
            for i = 1:size(dh_params, 1)
                theta = dh_params(i, 1);
                d = dh_params(i, 2);
                a = dh_params(i, 3);
                alpha = dh_params(i, 4);
        
                % Calculate transformation matrix
                T_i = dh_transform(theta, d, a, alpha);
                T = T * T_i;
            end
            % Extract end effector 
            X = T(1, 4);
            Y = T(2, 4);
            Z = T(3, 4);

            % Display the calculated values
            disp(['X: ', num2str(X)]);
            disp(['Y: ', num2str(Y)]);
            disp(['Z: ', num2str(Z)]);
            disp('Transformation Matrix T:');
            disp(T);

            app.x.Value = X;
            app.y.Value = Y;
            app.z.Value = Z;


            Simulation();
            allendeffectors(app, linspace(-180, 180, 100), linspace(-180, 180, 100), linspace(-180, 180, 100), 100);
            
            
        function allendeffectors(app, Theta1_range, Theta2_range, Theta3_range, num_points)
            % Initialize arrays to store end effector positions
            X_end_effector = zeros(num_points^3, 1);
            Y_end_effector = zeros(num_points^3, 1);
            Z_end_effector = zeros(num_points^3, 1);
        
            index = 1;
            for i = 1:num_points
                for j = 1:num_points
                    for k = 1:num_points
                        [X_end_effector(index), Y_end_effector(index), Z_end_effector(index)] = calculateEndEffectorPosition(app, Theta1_range(i), Theta2_range(j), Theta3_range(k));
                        index = index + 1;
                    end
                end
            end
        
            scatter3(app.UIAxes2, X_end_effector, Y_end_effector, Z_end_effector, '.', 'MarkerFaceColor', 'b');
        
            xlim(app.UIAxes2, [-10, 10]); 
            ylim(app.UIAxes2, [-10, 10]); 
            zlim(app.UIAxes2, [-10, 10]); 
        
            xlabel(app.UIAxes2, 'X-axis');
            ylabel(app.UIAxes2, 'Y-axis');
            zlabel(app.UIAxes2, 'Z-axis');
            title(app.UIAxes2, 'All End Effector Positions');
        
            axis(app.UIAxes2, 'equal'); % Make axes equal to maintain aspect ratio
            axis(app.UIAxes2, [-40, 40, -40, 40, -40, 40]); % Adjust as needed
        end


        function [X, Y, Z] = calculateEndEffectorPosition(app, Theta1, Theta2, Theta3)
            X = app.a1.Value * cosd(Theta1) + app.a2.Value * cosd(Theta1 + Theta2) + app.a3.Value * cosd(Theta1 + Theta2 + Theta3);
            Y = app.a1.Value * sind(Theta1) + app.a2.Value * sind(Theta1 + Theta2) + app.a3.Value * sind(Theta1 + Theta2 + Theta3);
            Z = 0; % Assuming the end effector is in the XY plane
        end
        
        function Simulation()
        
            % Plot the robot links manually
            L1 = app.a1.Value; 
            L2 = app.a2.Value; 
            L3 = app.a1.Value; 
            
            % Forward kinematics tof each joint
            X1 = 0;
            Y1 = 0;
            Z1 = 0;
            
            X2 = L1 * cosd(Theta1);
            Y2 = L1 * sind(Theta1);
            Z2 = 0; 
            
            X3 = X2 + L2 * cosd(Theta1 + Theta2);
            Y3 = Y2 + L2 * sind(Theta1 + Theta2);
            Z3 = 0; 
            
            X4 = X3 + L3 * cosd(Theta1 + Theta2 + Theta3);
            Y4 = Y3 + L3 * sind(Theta1 + Theta2 + Theta3);
            Z4 =0; 
            

            % radius of the cylinders the joints
            radius = 2;
            
            % Generate coordinates for the cylinder representing joint 1
            [x_joint1, y_joint1, z_joint1] = cylinder(radius);
            z_joint1 = z_joint1 * 5 -2.5; % Adjust the height of the cylinder
            % Translate the cylinder to the position of joint 1
            x_joint1 = x_joint1 + X2;
            y_joint1 = y_joint1 + Y2;
            
            % Generate coordinates for the cylinder representing joint 2
            [x_joint2, y_joint2, z_joint2] = cylinder(radius);
            z_joint2 = z_joint2 * 5 -2.5; % Adjust the height of the cylinder
            % Translate the cylinder to the position of joint 2
            x_joint2 = x_joint2 + X3;
            y_joint2 = y_joint2 + Y3;


            % Define the radius of the sphere
            radius = 2;
            
            % Generate coordinates for the sphere
            [xs, ys, zs] = sphere;
            
            % Scale the sphere to the desired radius
            xs = xs * radius;
            ys = ys * radius;
            zs = zs * radius;


            plot3(app.UIAxes, X, Y, Z, 'o', 'MarkerSize', 10);
            
            % Plot the robot links and cylinders
            hold(app.UIAxes, 'on'); % Hold the plot to overlay multiple elements
            plot3(app.UIAxes, [0, 0], [0, 0], [-40, 0], 'Color', [0.5, 0.5, 0.5], 'LineWidth', 5);
            plot3(app.UIAxes, [X1, X2], [Y1, Y2], [Z1, Z2], 'b', 'LineWidth', 13); % Link 1
            plot3(app.UIAxes, [X2, X3], [Y2, Y3], [Z2, Z3], 'g', 'LineWidth', 13); % Link 2
            plot3(app.UIAxes, [X3, X4], [Y3, Y4], [Z3, Z4], 'r', 'LineWidth', 13); % Link 3
            surf(app.UIAxes, x_joint1, y_joint1, z_joint1, 'FaceColor', 'red', 'EdgeColor', 'none'); % Cylinder representing joint 1
            surf(app.UIAxes, x_joint2, y_joint2, z_joint2, 'FaceColor', 'red', 'EdgeColor', 'none'); % Cylinder representing joint 2
             % Plot the sphere at the center of the axes
            surf(app.UIAxes, xs, ys, zs, 'FaceColor', [0.5, 0.5, 0.5], 'EdgeColor', 'none');
            
            xlim(app.UIAxes, [-5, 5]);
            ylim(app.UIAxes, [-5, 5]);
            zlim(app.UIAxes, [-5, 5]);
            hold(app.UIAxes, 'off'); % Release the plot hold


            % Plot the rectangle as a patch
            x_rect = [-40, -40, 40, 40];
            y_rect = [-40, 40, 40, -40];
            z_rect = [-40, -40, -40, -40];
            patch(app.UIAxes, x_rect, y_rect, z_rect, [0.8, 0.8, 0.8], 'FaceAlpha', 0.5); % Gray color with transparency
            
            
            % Set axes limits and aspect ratio
            axis(app.UIAxes, 'equal'); % Make axes equal to maintain aspect ratio
            % Set axes limits to make the plot larger
            axis(app.UIAxes, [-40, 40, -40, 40, -40, 40]); % Adjust as needed
        end

        end

        % Button pushed function: GetInverseKinamaticsButton
        function GetInverseKinamaticsButtonPushed(app, event)
            x_goal = app.x.Value;
            y_goal = app.y.Value;
            z_goal = app.z.Value;
        
            goal_point = [x_goal, y_goal, z_goal]; % the goal point
            xi = 0.1; % Set the step siz
        
            error_margin = 0.01; 
            initial_config = [app.theta1.Value, app.theta2.Value, app.theta3.Value]; % initt configuration
            
            a1 = 10;
            a2 = 10;
            a3 = 10;
            
            % Set the number of joints
            num_joints = 3;
        
            % Set the function handle for forward kinematics
            kinematics = @(q) [
                a1 * cosd(q(1)) + a2 * cosd(q(1) + q(2)) + a3 * cosd(q(1) + q(2) + q(3));
                a1 * sind(q(1)) + a2 * sind(q(1) + q(2)) + a3 * sind(q(1) + q(2) + q(3));
                % Assuming z is const
                z_goal; 
            ];
        
            tol = 1e-6;
            max_iter = 1000; 
            q = initial_config;
        
            for iter = 1:max_iter
        
                current_position = kinematics(q);
        
                % Jacobian matrix 
                J = zeros(3, num_joints);
                for i = 1:num_joints
                    q_perturbed = q;
                    q_perturbed(i) = q_perturbed(i) + xi;
                    
                    perturbed_position = kinematics(q_perturbed);
                    
                    %Jacobian column
                    J(:, i) = (perturbed_position - current_position) / xi;
                end
        
                % Compute the change in joint angles
                delta_q = pinv(J) * (goal_point - current_position);
        
                % Update the joint angles
                q = q + delta_q';
        
                % Check for convergence
                if norm(goal_point - current_position) < error_margin || norm(delta_q) < tol
                    break;
                end
            end
        
            app.theta1.Value = q(1);
            app.theta2.Value = q(2);
            app.theta3.Value = q(3);
        
            % Plot end effector positioN
            hold(app.UIAxes, 'on');
            plot3(app.UIAxes, 0, 0, 0, 'o', 'MarkerSize', 10);
            plot3(app.UIAxes, x_goal, y_goal, z_goal, 'o', 'MarkerSize', 10);
            hold(app.UIAxes, 'off');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ForwardandInverseKinamaticsSolverPanel = uipanel(app.UIFigure);
            app.ForwardandInverseKinamaticsSolverPanel.Title = 'Forward and Inverse Kinamatics Solver';
            app.UIAxes = uiaxes(app.ForwardandInverseKinamaticsSolverPanel);
            title(app.UIAxes, 'Workspace Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.ForwardandInverseKinamaticsSolverPanel);
            title(app.UIAxes2, 'all the possible points')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.NumberofLinksLabel = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel.Interpreter = 'tex';
            app.NumberofLinksLabel.Text = '\theta_1';
            app.NumberofLinksLabel_2 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_2.Interpreter = 'tex';
            app.NumberofLinksLabel_2.Text = '\theta_2';
            app.NumberofLinksLabel_3 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_3.Interpreter = 'tex';
            app.NumberofLinksLabel_3.Text = '\theta_3';
            app.GetInverseKinamaticsButton = uibutton(app.ForwardandInverseKinamaticsSolverPanel, 'push');
            app.GetInverseKinamaticsButton.ButtonPushedFcn = createCallbackFcn(app, @GetInverseKinamaticsButtonPushed, true);
            app.GetInverseKinamaticsButton.Text = 'Get Inverse Kinamatics';
            app.NumberofLinksLabel_4 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_4.Interpreter = 'latex';
            app.NumberofLinksLabel_4.Text = 'x';
            app.x = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.NumberofLinksLabel_5 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_5.Interpreter = 'latex';
            app.NumberofLinksLabel_5.Text = 'y';
            app.y = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.NumberofLinksLabel_6 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_6.Interpreter = 'latex';
            app.NumberofLinksLabel_6.Text = 'z';
            app.z = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.GetForwardKinamaticsButton = uibutton(app.ForwardandInverseKinamaticsSolverPanel, 'push');
            app.GetForwardKinamaticsButton.ButtonPushedFcn = createCallbackFcn(app, @GetForwardKinamaticsButtonPushed, true);
            app.GetForwardKinamaticsButton.Text = 'Get Forward Kinamatics';
            app.theta3 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.theta2 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.theta1 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.NumberofLinksLabel_7 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_7.Interpreter = 'tex';
            app.NumberofLinksLabel_7.Text = '\alpha_1';
            app.NumberofLinksLabel_8 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_8.Interpreter = 'tex';
            app.NumberofLinksLabel_8.Text = '\alpha_2';
            app.NumberofLinksLabel_9 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_9.Interpreter = 'tex';
            app.NumberofLinksLabel_9.Text = '\alpha_3';
            app.alpha3 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.alpha2 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.alpha1 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.NumberofLinksLabel_10 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_10.Interpreter = 'tex';
            app.NumberofLinksLabel_10.Text = 'd_1';
            app.NumberofLinksLabel_11 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_11.Interpreter = 'tex';
            app.NumberofLinksLabel_11.Text = 'd_2';
            app.NumberofLinksLabel_12 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_12.Interpreter = 'tex';
            app.NumberofLinksLabel_12.Text = 'd_3';
            app.d3 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.d2 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.d1 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.a3 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.NumberofLinksLabel_13 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_13.Interpreter = 'tex';
            app.NumberofLinksLabel_13.Text = 'a_1';
            app.NumberofLinksLabel_14 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_14.Interpreter = 'tex';
            app.NumberofLinksLabel_14.Text = 'a_2';
            app.a2 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.NumberofLinksLabel_15 = uilabel(app.ForwardandInverseKinamaticsSolverPanel);
            app.NumberofLinksLabel_15.Interpreter = 'tex';
            app.NumberofLinksLabel_15.Text = 'a_3';
            app.a1 = uieditfield(app.ForwardandInverseKinamaticsSolverPanel, 'numeric');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end